classdef app181 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Label_2             matlab.ui.control.Label
        tmaxEdit            matlab.ui.control.NumericEditField
        tmaxEditFieldLabel  matlab.ui.control.Label
        tminEdit            matlab.ui.control.NumericEditField
        tminEditFieldLabel  matlab.ui.control.Label
        btnSave             matlab.ui.control.Button
        btnAnalize          matlab.ui.control.Button
        Label               matlab.ui.control.Label
        btnClear            matlab.ui.control.Button
        btnLoad             matlab.ui.control.Button
        Slider              matlab.ui.control.Slider
        btnStop             matlab.ui.control.Button
        btnPlay             matlab.ui.control.Button
        UIAxes2             matlab.ui.control.UIAxes
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        x; % загруженный сигнал
        Fs; % частота дискретизации
        ku; % порог
        filename; % имя исходного файла
        filepath; % путь исходного файла
        t; % отсчеты времени сигнала
        y; % очищенный сигнал
        d1 % расположение эха (номер отсчета)
        pm % величина максимального пика
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Slider.Value = 0.35; % значение по умолчанию
            clear, clc, close all; % очищаем командное окно
        end

        % Button pushed function: btnLoad
        function btnLoadPushed(app, event)
            cla(app.UIAxes);
            cla(app.UIAxes2);
            app.x = [];
            app.Fs = [];
            app.d1 = [];
            [app.filename, app.filepath] = uigetfile({'*.wav'; '*.mp3'; '*.*'}, 'Выберите аудиофайл');
            if app.filename == 0
                % Отмена выбора
                return
            end
            [app.x, app.Fs] = audioread(fullfile(app.filepath, app.filename));
            app.t = (0:length(app.x)-1)/app.Fs;
            app.tmaxEdit.Value = max(app.t);
            plot(app.UIAxes, app.t, app.x);
            title(app.UIAxes,'Исходный сигнал');
            xlabel(app.UIAxes,'Время');
            ylabel(app.UIAxes,'Амплитуда');
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Button pushed function: btnPlay
        function btnPlayPushed(app, event)
            if isempty(app.x)
                msgbox('Нужно загрузить файл!','Error','error')
                return
            end
            set(app.btnPlay, 'Enable', 'off');
            set(app.btnLoad, 'Enable', 'off');
            sound(app.x, app.Fs);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.ku = app.Slider.Value;
        end

        % Button pushed function: btnClear
        function btnClearPushed(app, event)
            if isempty(app.x)
                msgbox('Нужно загрузить файл!','Error','error')
                return
            end
            if isempty(app.d1)
                msgbox('Нужно провести анализ!','Error','error')
                return
            end
            set(app.btnPlay, 'Enable', 'off');
            set(app.btnLoad, 'Enable', 'off');
            set(app.btnClear, 'Enable', 'off');
            set(app.btnAnalize, 'Enable', 'off');
            
            % Применяем БИХ-фильтр
            b = 1; % числитель
            a = [1, zeros(1,app.d1), 0.5]; % знаменатель
            %app.y_clean = filter(b,a,app.x); % фильтруем сигнал
            %реализуем БИХ-фильтр, учитвая, что значение выходного сигнала БИХ-фильтра
            % зависит не только от текущего и предыдущего значения входного сигнала,
            % но и от предыдущего значения выходного сигнала
            N1 = length(app.x); % переменные, хранящие длины соответствующих массивов
            N2 = length(a)-1;
            N3 = length(b);
            a(1) = []; % удаляем первый элемент a, так как он всегда равен 1.
            % создаем массив нулей для хранения текущих и предыдущих значений
            app.y = zeros(1, N1);
            xpred = zeros(1, N3-1);
            ypred = zeros(1, N2);
            % рекурсивно вычисляем значение сигнала
            for ii = 1:N1
                xpred = [app.x(ii), xpred(1:N3-2)]; % обновляем массив предыдущих значений
                % для входного сигнала
                % и вычисляем текущее значение сигнала по разностному
                % уравнению
                app.y(ii) = b*xpred'-a*ypred';
                %после чего обновляем массив предыдущих значений для
                %выходного сигнала
                ypred = [app.y(ii), ypred(1:N2-1)];
            end
            plot(app.UIAxes2, app.t, app.y)
            title(app.UIAxes2,'Очищенный сигнал');
            xlabel(app.UIAxes2,'Время');
            ylabel(app.UIAxes2,'Амплитуда');
            sound(app.y, app.Fs);
            
            set(app.btnPlay, 'Enable', 'on');
            set(app.btnLoad, 'Enable', 'on');
            set(app.btnClear, 'Enable', 'on');
            set(app.btnAnalize, 'Enable', 'on');
        end

        % Button pushed function: btnStop
        function btnStopPushed(app, event)
            set(app.btnPlay, 'Enable', 'on');
            set(app.btnLoad, 'Enable', 'on');
            clear sound;
        end

        % Button pushed function: btnAnalize
        function btnAnalizeButtonPushed(app, event)
            if isempty(app.x)
                msgbox('Нужно загрузить файл!','Error','error')
                return
            end
            set(app.btnPlay, 'Enable', 'off');
            set(app.btnLoad, 'Enable', 'off');
            set(app.btnClear, 'Enable', 'off');
            set(app.btnAnalize, 'Enable', 'off');
            
            a = app.Slider.Value;
            tmin = app.tminEdit.Value; % минимальное время появления эха
            tmax = app.tmaxEdit.Value; % максимальное время появления эха
            % АКФ файла
            %[xc,lags] = xcorr(app.x,'coeff');
            N=length(app.x);
            lags = 0:N-1;
            R=zeros(1,N);
            for k=lags
                R(k+1)=sum( app.x(1:(N-k)).*app.x((1+k):N) );
            end
            R=R/R(1); % нормируем
            
            tlags = lags/app.Fs; % перевод лагов в секунды
            idx = find((tlags>=tmin)&(tlags<=tmax));
            
            % берем нужную часть:
            xc = abs(R(idx)); % нас интересуют только положит.
            tlg = tlags(idx);
            
            %[p,id]=findpeaks(xc,'MinPeakHeight',a); % находим максимумы
            p = zeros(1,N); % создадим массивы нулей
            id = zeros(1,N);
            k=0; % счетчик пиков
            for ii = 2:length(xc)-1
                if xc(ii)>a && xc(ii)>xc(ii-1) && xc(ii)>xc(ii+1)
                    % сравниваем значение в текущей позиции с пороговым значением,
                    % заданным слайдером a, и со значениями в предыдущем и
                    % следующем отсчете и если значение на текущем отсчете больше,
                    % чем в предыдущем и следующем отсчете и больше, чем пороговое значение a,
                    % то мы добавляем его в массив пиков и их индексов
                    k=k+1; % увеличиваем счетчик
                    p(k) = xc(ii);
                    id(k) = ii;
                end
            end
            % удаляем пустые значения:
            p(k+1:N)=[];
            id(k+1:N)=[];
            if k==0
                str = {'Пик не найден!','Попробуйте уменьшить коэффициент'};
                msgbox(str,"Внимание!")
            else
                [app.pm,j] = max(p); % самый большой пик и его номер
                % рисуем
                plot(app.UIAxes2,tlg,xc,tlg(id),p,'.r', tlg(id(j)),app.pm);
                str = ['tmaxpeack=',num2str(tlg(id(j)))];
                text(app.UIAxes2,tlg(id(j)),app.pm,str,'VerticalAlignment', 'bottom')
                title(app.UIAxes2,'АКФ');
                xlabel(app.UIAxes2,'Время');
                % определяем сдвиг (в отсчетах): номер максимума + номер
                % пропущенных отсчетов
                app.d1 = find(app.t>=tmin,1)+id(j)-3;
                %disp(app.t(app.d1))
            end
            set(app.btnPlay, 'Enable', 'on');
            set(app.btnLoad, 'Enable', 'on');
            set(app.btnClear, 'Enable', 'on');
            set(app.btnAnalize, 'Enable', 'on');
        end

        % Button pushed function: btnSave
        function btnSaveButtonPushed(app, event)
            if isempty(app.y)
                msgbox('Нужно провести очистку!','Error','error')
                return
            end
            deffn = strcat(app.filename(1:end-4),'_clear');
            [app.filename, app.filepath] = uiputfile({'*.wav'; '*.mp3'; '*.*'},...
                'Выберите аудиофайл',deffn);
            if app.filename == 0 % Отмена выбора
                return
            end
            audiowrite(fullfile(app.filepath, app.filename),app.y, app.Fs);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.btnPlay = uibutton(app.UIFigure, 'push');
            app.btnPlay.ButtonPushedFcn = createCallbackFcn(app, @btnPlayPushed, true);
            app.btnPlay.Text = 'Запуск';
            app.btnStop = uibutton(app.UIFigure, 'push');
            app.btnStop.ButtonPushedFcn = createCallbackFcn(app, @btnStopPushed, true);
            app.btnStop.Text = 'Стоп';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 1];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.btnLoad = uibutton(app.UIFigure, 'push');
            app.btnLoad.ButtonPushedFcn = createCallbackFcn(app, @btnLoadPushed, true);
            app.btnLoad.Text = 'Загрузка';
            app.btnClear = uibutton(app.UIFigure, 'push');
            app.btnClear.ButtonPushedFcn = createCallbackFcn(app, @btnClearPushed, true);
            app.btnClear.Text = 'Убрать эхо';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Порог';
            app.btnAnalize = uibutton(app.UIFigure, 'push');
            app.btnAnalize.ButtonPushedFcn = createCallbackFcn(app, @btnAnalizeButtonPushed, true);
            app.btnAnalize.Text = {'Анализ'; ''};
            app.btnSave = uibutton(app.UIFigure, 'push');
            app.btnSave.ButtonPushedFcn = createCallbackFcn(app, @btnSaveButtonPushed, true);
            app.btnSave.Text = 'Сохранить';
            app.tminEditFieldLabel = uilabel(app.UIFigure);
            app.tminEditFieldLabel.Text = 'tmin';
            app.tminEdit = uieditfield(app.UIFigure, 'numeric');
            app.tminEdit.Value = 0.2;
            app.tmaxEditFieldLabel = uilabel(app.UIFigure);
            app.tmaxEditFieldLabel.Text = 'tmax';
            app.tmaxEdit = uieditfield(app.UIFigure, 'numeric');
            app.tmaxEdit.Value = Inf;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Границы области анализа';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app181

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end